classdef image_enhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HistogramDisplayMenu          matlab.ui.container.Menu
        ImageBrighteningMenu          matlab.ui.container.Menu
        ImageNegativeMenu             matlab.ui.container.Menu
        ImageLogTransformationMenu    matlab.ui.container.Menu
        ImagePowerTransformationMenu  matlab.ui.container.Menu
        ImageContrastStretchingMenu   matlab.ui.container.Menu
        HistogramEqualizationMenu     matlab.ui.container.Menu
        HistogramSpecificationMenu    matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        ConfigPanel                   matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        LogArea                       matlab.ui.control.TextArea
        SystemLogLabel                matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        ReferenceSelectedImageLabel   matlab.ui.control.Label
        ReferenceImageButton          matlab.ui.control.Button
        InputSelectedImageLabel       matlab.ui.control.Label
        InputImageButton              matlab.ui.control.Button
        GammaValueEditField           matlab.ui.control.NumericEditField
        GammaValueEditFieldLabel      matlab.ui.control.Label
        CValueEditField               matlab.ui.control.NumericEditField
        CValueEditFieldLabel          matlab.ui.control.Label
        BValueEditField               matlab.ui.control.NumericEditField
        BValueEditFieldLabel          matlab.ui.control.Label
        AValueEditField               matlab.ui.control.NumericEditField
        AValueEditFieldLabel          matlab.ui.control.Label
        MakeGrayscaleSwitch           matlab.ui.control.Switch
        MakeGrayscaleSwitchLabel      matlab.ui.control.Label
        SaveResultImageSwitchLabel    matlab.ui.control.Label
        SaveResultImageSwitch         matlab.ui.control.Switch
        ReferenceImageLabel           matlab.ui.control.Label
        InputImageLabel               matlab.ui.control.Label
        Display                       matlab.ui.container.GridLayout
        ImageResultLabel              matlab.ui.control.Label
        ImageReferenceLabel           matlab.ui.control.Label
        ImageSourceLabel              matlab.ui.control.Label
        ImageSource                   matlab.ui.control.Image
        ImageResult                   matlab.ui.control.Image
        ImageReference                matlab.ui.control.Image
        ImageResultUIAxes             matlab.ui.control.UIAxes
        ImageReferenceUIAxes          matlab.ui.control.UIAxes
        ImageSourceUIAxes             matlab.ui.control.UIAxes
    end

    properties (Access = public)
        IMAGE_SOURCE
        IMAGE_SOURCE_NAME
        HIST_DATA_SOURCE
        HIST_DATA_SOURCE_R
        HIST_DATA_SOURCE_G
        HIST_DATA_SOURCE_B
        IMAGE_REFERENCE
        IMAGE_REFERENCE_NAME
        HIST_DATA_REFERENCE
        HIST_DATA_REFERENCE_R
        HIST_DATA_REFERENCE_G
        HIST_DATA_REFERENCE_B
        IMAGE_RESULT
        HIST_DATA_RESULT
        HIST_DATA_RESULT_R
        HIST_DATA_RESULT_G
        HIST_DATA_RESULT_B
        LOG_DATA
        IS_GRAYSCALED
        IS_SAVED
        A_VAL = 1
        B_VAL = 0
        C_VAL = 0
        GAMMA_VAL = 0
    end

    methods (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function browseImage(app)
            
        end

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            addpath("functions\histogram\");
            
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.InputSelectedImageLabel.Text = fileName;

                app.IMAGE_SOURCE = imread(fullFileName);
                app.IMAGE_SOURCE_NAME = fileName;

                % Handle if the result is Grayscale
                if (size(app.IMAGE_SOURCE, 3) == 1)
                    grayImage = app.IMAGE_SOURCE;
                    image_source_display = cat(3, grayImage, grayImage, grayImage);
                else
                    image_source_display = app.IMAGE_SOURCE;
                end
                
                % Display the image in the UIAxes
                app.ImageSource.ImageSource = image_source_display; 

                
                % Clear the axes before plotting new histograms
                cla(app.ImageSourceUIAxes);

                % Hold the UIAxes to plot multiple histograms on top of each other
                hold(app.ImageSourceUIAxes, 'on');
                
                x_axis = 0:255;
                [app.HIST_DATA_SOURCE, app.HIST_DATA_SOURCE_R, app.HIST_DATA_SOURCE_G, app.HIST_DATA_SOURCE_B] = histogram_calculate(app.IMAGE_SOURCE);
                
                % Insert histogram data to UIAxes
                if (size(app.IMAGE_SOURCE, 3) == 3)
                     bar(app.ImageSourceUIAxes, x_axis, app.HIST_DATA_SOURCE_R, 'FaceColor', 'r', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Red');
                     bar(app.ImageSourceUIAxes, x_axis, app.HIST_DATA_SOURCE_G, 'FaceColor', 'g', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Green');
                     bar(app.ImageSourceUIAxes, x_axis, app.HIST_DATA_SOURCE_B, 'FaceColor', 'b', 'EdgeColor', 'none','FaceAlpha', 0.2,'DisplayName', 'Color Blue');
                else % Grayscale
                    bar(app.ImageSourceUIAxes, x_axis, app.HIST_DATA_SOURCE, 'FaceColor', 'k', 'EdgeColor', 'none', 'FaceAlpha', 0.2,  'DisplayName', 'Color Gray');
                end

                % Add labels and title
                title(app.ImageSourceUIAxes, 'Histogram of Initial Image');
                xlabel(app.ImageSourceUIAxes, 'Pixel Values');
                ylabel(app.ImageSourceUIAxes, 'Frequency');

                % Show legend to differentiate between the datasets
                legend(app.ImageSourceUIAxes, 'show');
                
                % Release the hold
                hold(app.ImageSourceUIAxes, 'off');
            end
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            addpath("functions\program\");
            if isempty(app.IMAGE_SOURCE)
                app.LogArea.Value = sprintf("[ERROR] Make sure to input an image first!\n");
            else
                valid = true;
                if (app.ConfigPanel.Title == "Image Brightening")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_brightening(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.A_VAL, app.B_VAL);
                elseif (app.ConfigPanel.Title == "Image Negative")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_negative(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Image Log Transformation")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_log_transformation(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.C_VAL);
                elseif (app.ConfigPanel.Title == "Image Power Transformation")   
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_power_transformation(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED, app.C_VAL, app.GAMMA_VAL);
                elseif (app.ConfigPanel.Title == "Image Contrast Stretching")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_img_contrast_stretching(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Histogram Equalization")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_histogram_equalizer(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                elseif (app.ConfigPanel.Title == "Histogram Specification")
                    [app.IMAGE_RESULT, app.HIST_DATA_RESULT, app.HIST_DATA_RESULT_R, app.HIST_DATA_RESULT_G, app.HIST_DATA_RESULT_B, app.LOG_DATA] = func_histogram_specification(app.IMAGE_SOURCE, app.IMAGE_SOURCE_NAME, app.IMAGE_REFERENCE, app.IMAGE_REFERENCE_NAME, app.IS_GRAYSCALED, app.IS_SAVED);
                else
                    valid = false;
                end

                if (valid)
                    % Set Result
                    app.LogArea.Value = app.LOG_DATA;
    
                    % Handle if the result is Grayscale
                    if (size(app.IMAGE_RESULT, 3) == 1)
                        grayImage = app.IMAGE_RESULT;
                        image_result_display = cat(3, grayImage, grayImage, grayImage);
                    else
                        image_result_display = app.IMAGE_RESULT;
                    end
                    app.ImageResult.ImageSource = image_result_display;
                else
                    app.LogArea.Value = sprintf("[ERROR] %s is not valid!", app.ConfigPanel.Title);
                end
            end
                
        end

        % Value changed function: MakeGrayscaleSwitch
        function MakeGrayscaleSwitchValueChanged(app, event)
            grayscale_switch_val = app.MakeGrayscaleSwitch.Value;
            if (grayscale_switch_val == "On")
                app.IS_GRAYSCALED = 1;
            else
                app.IS_GRAYSCALED = 0;
            end
            app.LogArea.Value = sprintf("[STATE] Changing IS_GRAYSCALED variable to %s.\n", grayscale_switch_val);
        end

        % Value changed function: SaveResultImageSwitch
        function SaveResultImageSwitchValueChanged(app, event)
            save_switch_val = app.SaveResultImageSwitch.Value;
            if (save_switch_val == "On")
                app.IS_SAVED = 1;
            else
                app.IS_SAVED = 0;
            end
            app.LogArea.Value = sprintf("[STATE] Changing IS_SAVED variable to %s.\n", save_switch_val);
        end

        % Button pushed function: ReferenceImageButton
        function ReferenceImageButtonPushed(app, event)
            % Open file dialog to browse for an image file
            [fileName, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'});
            
            % If the user selected a file, display it in the UIAxes
            if ischar(fileName)
                % Full file path of the selected image
                fullFileName = fullfile(filePath, fileName);

                % Change label
                app.ReferenceSelectedImageLabel.Text = fileName;

                app.IMAGE_REFERENCE = imread(fullFileName);
                app.IMAGE_REFERENCE_NAME = fileName;

                % Handle if the result is Grayscale
                if (size(app.IMAGE_REFERENCE, 3) == 1)
                    grayImage = app.IMAGE_REFERENCE;
                    image_reference_display = cat(3, grayImage, grayImage, grayImage);
                else
                    image_reference_display = app.IMAGE_REFERENCE;
                end
                
                % Display the image in the UIAxes
                app.ImageReference.ImageSource = image_reference_display;  % Display the image
            end
        end

        % Menu selected function: HistogramDisplayMenu
        function HistogramDisplayMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Display';
        end

        % Menu selected function: ImageBrighteningMenu
        function ImageBrighteningMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Brightening';
        end

        % Menu selected function: ImageNegativeMenu
        function ImageNegativeMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Negative';
        end

        % Menu selected function: ImageLogTransformationMenu
        function ImageLogTransformationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Log Transformation';
        end

        % Menu selected function: ImagePowerTransformationMenu
        function ImagePowerTransformationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Power Transformation';
        end

        % Menu selected function: ImageContrastStretchingMenu
        function ImageContrastStretchingMenuSelected(app, event)
            app.ConfigPanel.Title = 'Image Contrast Stretching';
        end

        % Menu selected function: HistogramEqualizationMenu
        function HistogramEqualizationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Equalization';
        end

        % Menu selected function: HistogramSpecificationMenu
        function HistogramSpecificationMenuSelected(app, event)
            app.ConfigPanel.Title = 'Histogram Specification';
        end

        % Value changed function: AValueEditField
        function AValueEditFieldValueChanged(app, event)
            app.A_VAL = double(app.AValueEditField.Value);
            app.LogArea.Value = sprintf("[STATE] Changing A_VAL variable to %.2f.\n", app.A_VAL);
        end

        % Value changed function: BValueEditField
        function BValueEditFieldValueChanged(app, event)
            app.B_VAL = double(app.BValueEditField.Value);
            app.LogArea.Value = sprintf("[STATE] Changing B_VAL variable to %.2f.\n", app.B_VAL);
        end

        % Value changed function: CValueEditField
        function CValueEditFieldValueChanged(app, event)
            app.C_VAL = double(app.CValueEditField.Value);
            app.LogArea.Value = sprintf("[STATE] Changing C_VAL variable to %.2f.\n", app.C_VAL);
        end

        % Value changed function: GammaValueEditField
        function GammaValueEditFieldValueChanged(app, event)
            app.GAMMA_VAL = double(app.GammaValueEditField.Value);
            app.LogArea.Value = sprintf("[STATE] Changing GAMMA_VAL variable to %.2f.\n", app.GAMMA_VAL);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramDisplayMenu = uimenu(app.UIFigure);
            app.HistogramDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramDisplayMenuSelected, true);
            app.HistogramDisplayMenu.Text = 'Histogram Display';
            app.ImageBrighteningMenu = uimenu(app.UIFigure);
            app.ImageBrighteningMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageBrighteningMenuSelected, true);
            app.ImageBrighteningMenu.Text = 'Image Brightening';
            app.ImageNegativeMenu = uimenu(app.UIFigure);
            app.ImageNegativeMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageNegativeMenuSelected, true);
            app.ImageNegativeMenu.Text = 'Image Negative';
            app.ImageLogTransformationMenu = uimenu(app.UIFigure);
            app.ImageLogTransformationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageLogTransformationMenuSelected, true);
            app.ImageLogTransformationMenu.Text = 'Image Log Transformation';
            app.ImagePowerTransformationMenu = uimenu(app.UIFigure);
            app.ImagePowerTransformationMenu.MenuSelectedFcn = createCallbackFcn(app, @ImagePowerTransformationMenuSelected, true);
            app.ImagePowerTransformationMenu.Text = 'Image Power Transformation';
            app.ImageContrastStretchingMenu = uimenu(app.UIFigure);
            app.ImageContrastStretchingMenu.MenuSelectedFcn = createCallbackFcn(app, @ImageContrastStretchingMenuSelected, true);
            app.ImageContrastStretchingMenu.Text = 'Image Contrast Stretching';
            app.HistogramEqualizationMenu = uimenu(app.UIFigure);
            app.HistogramEqualizationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramEqualizationMenuSelected, true);
            app.HistogramEqualizationMenu.Text = 'Histogram Equalization';
            app.HistogramSpecificationMenu = uimenu(app.UIFigure);
            app.HistogramSpecificationMenu.MenuSelectedFcn = createCallbackFcn(app, @HistogramSpecificationMenuSelected, true);
            app.HistogramSpecificationMenu.Text = 'Histogram Specification';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Display = uigridlayout(app.GridLayout);
            app.ImageSourceUIAxes = uiaxes(app.Display);
            title(app.ImageSourceUIAxes, 'Title')
            xlabel(app.ImageSourceUIAxes, 'X')
            ylabel(app.ImageSourceUIAxes, 'Y')
            zlabel(app.ImageSourceUIAxes, 'Z')
            app.ImageReferenceUIAxes = uiaxes(app.Display);
            title(app.ImageReferenceUIAxes, 'Title')
            xlabel(app.ImageReferenceUIAxes, 'X')
            ylabel(app.ImageReferenceUIAxes, 'Y')
            zlabel(app.ImageReferenceUIAxes, 'Z')
            app.ImageResultUIAxes = uiaxes(app.Display);
            title(app.ImageResultUIAxes, 'Title')
            xlabel(app.ImageResultUIAxes, 'X')
            ylabel(app.ImageResultUIAxes, 'Y')
            zlabel(app.ImageResultUIAxes, 'Z')
            app.ImageReference = uiimage(app.Display);
            app.ImageResult = uiimage(app.Display);
            app.ImageSource = uiimage(app.Display);
            app.ImageSourceLabel = uilabel(app.Display);
            app.ImageSourceLabel.Text = 'Source Image';
            app.ImageReferenceLabel = uilabel(app.Display);
            app.ImageReferenceLabel.Text = 'Reference Image';
            app.ImageResultLabel = uilabel(app.Display);
            app.ImageResultLabel.Text = 'Result Image';
            app.ConfigPanel = uipanel(app.GridLayout);
            app.ConfigPanel.Title = 'Histogram Display';
            app.GridLayout3 = uigridlayout(app.ConfigPanel);
            app.InputImageLabel = uilabel(app.GridLayout3);
            app.InputImageLabel.Text = 'Input Image';
            app.ReferenceImageLabel = uilabel(app.GridLayout3);
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.SaveResultImageSwitch = uiswitch(app.GridLayout3, 'slider');
            app.SaveResultImageSwitch.ValueChangedFcn = createCallbackFcn(app, @SaveResultImageSwitchValueChanged, true);
            app.SaveResultImageSwitchLabel = uilabel(app.GridLayout3);
            app.SaveResultImageSwitchLabel.Text = 'Save Result Image';
            app.MakeGrayscaleSwitchLabel = uilabel(app.GridLayout3);
            app.MakeGrayscaleSwitchLabel.Text = 'Make Grayscale';
            app.MakeGrayscaleSwitch = uiswitch(app.GridLayout3, 'slider');
            app.MakeGrayscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @MakeGrayscaleSwitchValueChanged, true);
            app.AValueEditFieldLabel = uilabel(app.GridLayout3);
            app.AValueEditFieldLabel.Text = 'A Value';
            app.AValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AValueEditField.ValueChangedFcn = createCallbackFcn(app, @AValueEditFieldValueChanged, true);
            app.AValueEditField.Placeholder = '1';
            app.AValueEditField.Value = 1;
            app.BValueEditFieldLabel = uilabel(app.GridLayout3);
            app.BValueEditFieldLabel.Text = 'B Value';
            app.BValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.BValueEditField.ValueChangedFcn = createCallbackFcn(app, @BValueEditFieldValueChanged, true);
            app.BValueEditField.Placeholder = '0';
            app.CValueEditFieldLabel = uilabel(app.GridLayout3);
            app.CValueEditFieldLabel.Text = 'C Value';
            app.CValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CValueEditField.ValueChangedFcn = createCallbackFcn(app, @CValueEditFieldValueChanged, true);
            app.CValueEditField.Placeholder = '0';
            app.GammaValueEditFieldLabel = uilabel(app.GridLayout3);
            app.GammaValueEditFieldLabel.Text = 'Gamma Value';
            app.GammaValueEditField = uieditfield(app.GridLayout3, 'numeric');
            app.GammaValueEditField.ValueChangedFcn = createCallbackFcn(app, @GammaValueEditFieldValueChanged, true);
            app.GammaValueEditField.Placeholder = '0';
            app.InputImageButton = uibutton(app.GridLayout3, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Browse...';
            app.InputSelectedImageLabel = uilabel(app.GridLayout3);
            app.InputSelectedImageLabel.Text = 'No Selected Image';
            app.ReferenceImageButton = uibutton(app.GridLayout3, 'push');
            app.ReferenceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ReferenceImageButtonPushed, true);
            app.ReferenceImageButton.Text = 'Browse...';
            app.ReferenceSelectedImageLabel = uilabel(app.GridLayout3);
            app.ReferenceSelectedImageLabel.Text = 'No Selected Image';
            app.ExecuteButton = uibutton(app.GridLayout3, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SystemLogLabel = uilabel(app.GridLayout3);
            app.SystemLogLabel.Text = 'System Log';
            app.LogArea = uitextarea(app.GridLayout3);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_enhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @browseImage)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end